classdef PlantSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        NextButton                   matlab.ui.control.Button
        PrevButton                   matlab.ui.control.Button
        CurrentImageDropDown         matlab.ui.control.DropDown
        MaskPaintModeButtonGroup     matlab.ui.container.ButtonGroup
        RootLamp                     matlab.ui.control.Lamp
        ShootLamp                    matlab.ui.control.Lamp
        BackgroundLamp               matlab.ui.control.Lamp
        BackgroundButton             matlab.ui.control.ToggleButton
        ShootButton                  matlab.ui.control.ToggleButton
        RootButton                   matlab.ui.control.ToggleButton
        OpenDirButton                matlab.ui.control.Button
        MiscTab                      matlab.ui.container.Tab
        uncertainLabel               matlab.ui.control.Label
        ColorSelectorMaskUncertain   wt.ColorSelector
        SliderMaskAlpha              matlab.ui.control.Slider
        MaskAlphaLabel               matlab.ui.control.Label
        rootLabel                    matlab.ui.control.Label
        shootLabel                   matlab.ui.control.Label
        ColorSelectorMaskRoot        wt.ColorSelector
        ColorSelectorMaskShoot       wt.ColorSelector
        backgroundLabel              matlab.ui.control.Label
        ColorSelectorMaskBackground  wt.ColorSelector
        ShowmaskbackgroundcolorCheckBox  matlab.ui.control.CheckBox
        UIAxesSeg                    matlab.ui.control.UIAxes
    end

    
    % PPrivate variables:
    properties (Access = private)

        % These parameters can be changed:
        fixedImageWidth = 995;      % All images will be set to this width for consistency.
        fixedImageHeight = 1016;    % All images will be set to this height for consistency.
        bgColor = [0, 0, 0];        % Color for displaying background pixels.
        shootColor = [0, 1, 1];     % Color for displaying shoot pixels.
        rootColor = [1, 0, 0];      % Color for displaying root pixels.
        uncertainColor = [1, 1, 0]; % Color for displaying uncertain pixels.
        maskAlpha = 0.5;            % Mask transparency value.
        showBackgroundColor = false;% Toggle display of background color.

        % These parameters should not be changed unless you know what you
        % are doing:
        inputDir = "";              % File folder or directory where all input images are.
        inputFiles = [];            % List of input filenames.
        curInputIndex = 1;          % Index of current image from from list. 
        inputFilename = "";         % Filename of current image with directory.
        bgMaskValue = 0;            % Value of background pixels in mask image.
        shootMaskValue = 70;        % Value of shoot pixels in mask image.
        rootMaskValue = 140;        % Value of root pixels in mask image.
        uncertainMaskValue = 210;   % Value of uncertain (root or shoot) pixels in mask image.
        activeMaskValue = 0;        % Value to be painted in mask using mouse.
        segColorMap = 0;
        inputImage = 0;             % Input image with 3 channels RGB of uint8.
        mask = 0;                   % Output mask (uint8 single channel).
        imagePlot = 0;              % Handle to main image plot.
        mouseDown = false;          % Is left mouse button currently pressed?

        binaryMask = 0;
        shootMask = 0;
    end
    
    % Private functions:
    methods (Access = private)
        
        function UpdateSegmentation(app)
            imagesc(app.inputImage, "Parent", app.UIAxesSeg);
            hold(app.UIAxesSeg, "on");
            app.imagePlot = imagesc(app.mask, "Parent", app.UIAxesSeg);
            colormap(app.UIAxesSeg, app.segColorMap);
            app.UIAxesSeg.XLim = [0 app.imagePlot.XData(2)];
            app.UIAxesSeg.YLim = [0 app.imagePlot.YData(2)];
            hold(app.UIAxesSeg, "off")
            
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'AlphaData', alphaImage);
            set(app.imagePlot, 'Tag', 'WindowTag')
        end
        
        function InitializeVariables(app)
            app.SliderMaskAlpha.Value = app.maskAlpha;
            app.ShowmaskbackgroundcolorCheckBox.Value = app.showBackgroundColor;
            app.ColorSelectorMaskBackground.Value = app.bgColor;
            app.BackgroundLamp.Color = app.bgColor;
            app.ColorSelectorMaskShoot.Value = app.shootColor;
            app.ShootLamp.Color = app.shootColor;
            app.ColorSelectorMaskRoot.Value = app.rootColor;
            app.RootLamp.Color = app.rootColor;
            app.ColorSelectorMaskUncertain.Value = app.uncertainColor;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor; app.uncertainColor];
        end
        
        function ComputeMaskFromInput(app)

            rootsMask = SegmentRoots(app.inputImage);
            shootsMask = SegmentShoots(app.inputImage);
            app.mask = (uint8(rootsMask) * app.rootMaskValue) + (uint8(shootsMask) * app.shootMaskValue);
        end
        
        function UpdateMaskFromMouse(app, event)
            mouseButtonFlag = get(event.Source,'SelectionType');
            mousePos = get(app.UIAxesSeg,'CurrentPoint');
            mouseRowPos = round(mousePos(1,2));
            mouseColPos = round(mousePos(1,1));

            if(strcmp(mouseButtonFlag, 'normal'))
                app.mask(mouseRowPos, mouseColPos) = app.activeMaskValue;
            else
                app.mask(mouseRowPos, mouseColPos) = app.bgMaskValue;
            end
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'CData', app.mask, 'alphadata', alphaImage);
        end
        
        function alphaImage = ComputeMaskAlphaImage(app)
            if app.showBackgroundColor
                alphaImage = ones(size(app.mask)) * app.maskAlpha;
            else
                alphaImage = (single(app.mask > app.bgMaskValue)) * app.maskAlpha;
            end
        end

        function result = GetTifImageFiles(~, inputDir)
            listing = dir(inputDir + "\*.tif");
            numFilenames = size(listing, 1);
            fileIndex = 1;
            result = strings(1, size(listing, 1));
            while(fileIndex <= numFilenames)
                result(1, fileIndex) = string(listing(fileIndex).name);
                fileIndex = fileIndex + 1;
            end
        end

        function LoadNewImage(app)
            app.inputFilename = app.inputDir + "\" + app.inputFiles(app.curInputIndex);
            app.inputImage = imread(app.inputFilename);
            app.inputImage = imresize(app.inputImage, [app.fixedImageHeight, app.fixedImageWidth]);

            ComputeMaskFromInput(app);
            UpdateSegmentation(app);
        end

        function UpdateImagePlotAppearance(app)
            alphaImage = ComputeMaskAlphaImage(app);
            set(app.imagePlot, 'CData', app.mask, 'alphadata', alphaImage);
            colormap(app.UIAxesSeg, app.segColorMap);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitializeVariables(app);
        end

        % Button pushed function: OpenDirButton
        function OpenDirButtonPushed(app, event)
            
            app.inputDir = uigetdir;
            app.inputFiles = GetTifImageFiles(app, app.inputDir);
            app.curInputIndex = 1;
            app.CurrentImageDropDown.Items = app.inputFiles;
            app.CurrentImageDropDown.ItemsData = 1 : size(app.inputFiles, 2);
            
            LoadNewImage(app);
        end

        % Value changed function: SliderMaskAlpha
        function SliderMaskAlphaValueChanged(app, event)
            app.maskAlpha = app.SliderMaskAlpha.Value;
            UpdateImagePlotAppearance(app);
        end

        % Value changed function: ShowmaskbackgroundcolorCheckBox
        function ShowmaskbackgroundcolorCheckBoxValueChanged(app, event)
            app.showBackgroundColor = app.ShowmaskbackgroundcolorCheckBox.Value;
            UpdateImagePlotAppearance(app);
        end

        % Callback function: ColorSelectorMaskBackground
        function ColorSelectorMaskBackgroundValueChanged(app, event)
            app.bgColor = app.ColorSelectorMaskBackground.Value;
            app.BackgroundLamp.Color = app.bgColor;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor; app.uncertainColor];
            UpdateImagePlotAppearance(app);
        end

        % Callback function: ColorSelectorMaskShoot
        function ColorSelectorMaskShootValueChanged(app, event)
            app.shootColor = app.ColorSelectorMaskShoot.Value;
            app.ShootLamp.Color = app.shootColor;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor; app.uncertainColor];
            UpdateImagePlotAppearance(app);
        end

        % Callback function: ColorSelectorMaskRoot
        function ColorSelectorMaskRootValueChanged(app, event)
            app.rootColor = app.ColorSelectorMaskRoot.Value;
            app.RootLamp.Color = app.rootColor;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor; app.uncertainColor];
            UpdateImagePlotAppearance(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = true;
                UpdateMaskFromMouse(app, event);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                if(app.mouseDown)
                    UpdateMaskFromMouse(app, event);
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'WindowTag')
                app.mouseDown = false;
            end
        end

        % Selection changed function: MaskPaintModeButtonGroup
        function MaskPaintModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MaskPaintModeButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text, "Background"))
                app.activeMaskValue = app.bgMaskValue;
            end
            if(strcmp(selectedButton.Text, "Root"))
                app.activeMaskValue = app.rootMaskValue;
            end
            if(strcmp(selectedButton.Text, "Shoot"))
                app.activeMaskValue = app.shootMaskValue;
            end
        end

        % Value changed function: CurrentImageDropDown
        function CurrentImageDropDownValueChanged(app, event)
            app.curInputIndex = app.CurrentImageDropDown.Value;
            LoadNewImage(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.curInputIndex == size(app.inputFiles, 2))
                message = {'End of files list!','Use dropdown menu to select file.'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            else
                app.curInputIndex = app.curInputIndex + 1;
                set(app.CurrentImageDropDown, 'Value', app.curInputIndex);
                LoadNewImage(app);
            end            
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            if (app.curInputIndex == 1)
                message = {'Beginning of files list!','Use dropdown menu to select file.'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            else
                app.curInputIndex = app.curInputIndex - 1;
                set(app.CurrentImageDropDown, 'Value', app.curInputIndex);
                LoadNewImage(app);
            end    
        end

        % Callback function: ColorSelectorMaskUncertain
        function ColorSelectorMaskUncertainValueChanged(app, event)
            app.uncertainColor = app.ColorSelectorMaskUncertain.Value;
            app.segColorMap = [app.bgColor; app.shootColor; app.rootColor; app.uncertainColor];
            UpdateImagePlotAppearance(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxesSeg = uiaxes(app.UIFigure);
            app.UIAxesSeg.XTick = [];
            app.UIAxesSeg.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OpenDirButton = uibutton(app.MainTab, 'push');
            app.OpenDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirButtonPushed, true);
            app.OpenDirButton.Text = 'Open Dir';
            app.MaskPaintModeButtonGroup = uibuttongroup(app.MainTab);
            app.MaskPaintModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskPaintModeButtonGroupSelectionChanged, true);
            app.MaskPaintModeButtonGroup.Title = 'Mask Paint Mode:';
            app.RootButton = uitogglebutton(app.MaskPaintModeButtonGroup);
            app.RootButton.Text = 'Root';
            app.ShootButton = uitogglebutton(app.MaskPaintModeButtonGroup);
            app.ShootButton.Text = 'Shoot';
            app.BackgroundButton = uitogglebutton(app.MaskPaintModeButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.BackgroundButton.Value = true;
            app.BackgroundLamp = uilamp(app.MaskPaintModeButtonGroup);
            app.ShootLamp = uilamp(app.MaskPaintModeButtonGroup);
            app.RootLamp = uilamp(app.MaskPaintModeButtonGroup);
            app.CurrentImageDropDown = uidropdown(app.MainTab);
            app.CurrentImageDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentImageDropDownValueChanged, true);
            app.PrevButton = uibutton(app.MainTab, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev.';
            app.NextButton = uibutton(app.MainTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.ShowmaskbackgroundcolorCheckBox = uicheckbox(app.MiscTab);
            app.ShowmaskbackgroundcolorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmaskbackgroundcolorCheckBoxValueChanged, true);
            app.ShowmaskbackgroundcolorCheckBox.Text = 'Show mask background color';
            app.ColorSelectorMaskBackground = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskBackground.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskBackgroundValueChanged, true);
            app.backgroundLabel = uilabel(app.MiscTab);
            app.backgroundLabel.Text = 'background';
            app.ColorSelectorMaskShoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskShoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskShootValueChanged, true);
            app.ColorSelectorMaskRoot = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskRoot.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskRootValueChanged, true);
            app.shootLabel = uilabel(app.MiscTab);
            app.shootLabel.Text = 'shoot';
            app.rootLabel = uilabel(app.MiscTab);
            app.rootLabel.Text = 'root';
            app.MaskAlphaLabel = uilabel(app.MiscTab);
            app.MaskAlphaLabel.Text = 'Mask Alpha';
            app.SliderMaskAlpha = uislider(app.MiscTab);
            app.SliderMaskAlpha.Limits = [0 1];
            app.SliderMaskAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderMaskAlphaValueChanged, true);
            app.ColorSelectorMaskUncertain = wt.ColorSelector(app.MiscTab);
            app.ColorSelectorMaskUncertain.ValueChangedFcn = createCallbackFcn(app, @ColorSelectorMaskUncertainValueChanged, true);
            app.uncertainLabel = uilabel(app.MiscTab);
            app.uncertainLabel.Text = 'uncertain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantSeg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end